classdef VortEx_GS_v3_6_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WriteSessionTimeEditField       matlab.ui.control.EditField
        WriteSessionTimeEditFieldLabel  matlab.ui.control.Label
        hmsLabel                        matlab.ui.control.Label
        ReadSessionTimeEditField        matlab.ui.control.EditField
        ReadSessionTimeEditFieldLabel   matlab.ui.control.Label
        BytesinBufferEditField          matlab.ui.control.NumericEditField
        BytesinBufferEditFieldLabel     matlab.ui.control.Label
        LiveControlPanel                matlab.ui.container.Panel
        HousekeepingSwitch              matlab.ui.control.Switch
        HousekeepingSwitchLabel         matlab.ui.control.Label
        CollectDataSwitch               matlab.ui.control.Switch
        CollectDataSwitchLabel          matlab.ui.control.Label
        WriteEnableSwitch               matlab.ui.control.Switch
        WriteEnableSwitchLabel          matlab.ui.control.Label
        WriteFileNameEditField          matlab.ui.control.EditField
        WriteFileNameEditFieldLabel     matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        SetupPanel                      matlab.ui.container.Panel
        GeneralLegendLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PlotSettingsPanel               matlab.ui.container.Panel
        PlotWidthSecondsEditField       matlab.ui.control.NumericEditField
        PlotWidthSecondsEditFieldLabel  matlab.ui.control.Label
        PlotUpdateRateHzEditField       matlab.ui.control.NumericEditField
        PlotUpdateRateHzEditFieldLabel  matlab.ui.control.Label
        InstrSelectDropDown             matlab.ui.control.DropDown
        InstrSelectDropDownLabel        matlab.ui.control.Label
        ReadModeSwitch                  matlab.ui.control.Switch
        ReadModeSwitchLabel             matlab.ui.control.Label
        MapFileNameEditField            matlab.ui.control.EditField
        MapFileNameEditFieldLabel       matlab.ui.control.Label
        LocalHostEditField              matlab.ui.control.EditField
        LocalHostEditFieldLabel         matlab.ui.control.Label
        ReadFileNameEditField           matlab.ui.control.EditField
        ReadFileNameEditFieldLabel      matlab.ui.control.Label
        LocalPortEditField              matlab.ui.control.NumericEditField
        LocalPortEditFieldLabel         matlab.ui.control.Label
        LoadGPSMapButton                matlab.ui.control.Button
        SelectFileButton                matlab.ui.control.Button
        FilePlaybackControlPanel        matlab.ui.container.Panel
        PlaybackSpeedxEditField         matlab.ui.control.NumericEditField
        PlaybackSpeedxEditFieldLabel    matlab.ui.control.Label
        PauseButton                     matlab.ui.control.StateButton
        EFPPanel                        matlab.ui.container.Panel
        BatMonEditField_4               matlab.ui.control.NumericEditField
        BatMonEditField_4Label          matlab.ui.control.Label
        VEditField_16                   matlab.ui.control.NumericEditField
        VEditField_16Label              matlab.ui.control.Label
        VEditField_15                   matlab.ui.control.NumericEditField
        VEditField_15Label              matlab.ui.control.Label
        VEditField_14                   matlab.ui.control.NumericEditField
        VEditField_14Label              matlab.ui.control.Label
        VEditField_13                   matlab.ui.control.NumericEditField
        VEditField_13Label              matlab.ui.control.Label
        VBatEditField_4                 matlab.ui.control.NumericEditField
        VBatEditField_4Label            matlab.ui.control.Label
        IntTempEditField_4              matlab.ui.control.NumericEditField
        IntTempEditField_4Label         matlab.ui.control.Label
        Temp3EditField_4                matlab.ui.control.NumericEditField
        Temp3EditField_4Label           matlab.ui.control.Label
        Temp2EditField_4                matlab.ui.control.NumericEditField
        Temp2EditField_4Label           matlab.ui.control.Label
        Temp1EditField_4                matlab.ui.control.NumericEditField
        Temp1EditField_4Label           matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        GPSPanel                        matlab.ui.container.Panel
        NumSatsEditField                matlab.ui.control.NumericEditField
        NumSatsEditFieldLabel           matlab.ui.control.Label
        HorzSpeedmsEditField            matlab.ui.control.NumericEditField
        HorzSpeedmsEditFieldLabel       matlab.ui.control.Label
        vUpmsEditField                  matlab.ui.control.NumericEditField
        vUpmsEditFieldLabel             matlab.ui.control.Label
        vNorthmsEditField               matlab.ui.control.NumericEditField
        vNorthmsEditFieldLabel          matlab.ui.control.Label
        vEastmsEditField                matlab.ui.control.NumericEditField
        vEastmsEditFieldLabel           matlab.ui.control.Label
        AltitudekmEditField             matlab.ui.control.NumericEditField
        AltitudekmEditFieldLabel        matlab.ui.control.Label
        LongitudedegEditField           matlab.ui.control.NumericEditField
        LongitudedegEditFieldLabel      matlab.ui.control.Label
        LatitudedegEditField            matlab.ui.control.NumericEditField
        LatitudedegEditFieldLabel       matlab.ui.control.Label
        ACCPanel                        matlab.ui.container.Panel
        DigTempEditField                matlab.ui.control.NumericEditField
        DigTempEditFieldLabel           matlab.ui.control.Label
        BatMonEditField_3               matlab.ui.control.NumericEditField
        BatMonEditField_3Label          matlab.ui.control.Label
        VEditField_12                   matlab.ui.control.NumericEditField
        VEditField_12Label              matlab.ui.control.Label
        VEditField_11                   matlab.ui.control.NumericEditField
        VEditField_11Label              matlab.ui.control.Label
        VEditField_10                   matlab.ui.control.NumericEditField
        VEditField_10Label              matlab.ui.control.Label
        VEditField_9                    matlab.ui.control.NumericEditField
        VEditField_9Label               matlab.ui.control.Label
        VBatEditField_3                 matlab.ui.control.NumericEditField
        VBatEditField_3Label            matlab.ui.control.Label
        IntTempEditField_3              matlab.ui.control.NumericEditField
        IntTempEditField_3Label         matlab.ui.control.Label
        Temp3EditField_3                matlab.ui.control.NumericEditField
        Temp3EditField_3Label           matlab.ui.control.Label
        Temp2EditField_3                matlab.ui.control.NumericEditField
        Temp2EditField_3Label           matlab.ui.control.Label
        Temp1EditField_3                matlab.ui.control.NumericEditField
        Temp1EditField_3Label           matlab.ui.control.Label
        PIPPanel                        matlab.ui.container.Panel
        BatMonEditField_2               matlab.ui.control.NumericEditField
        BatMonEditField_2Label          matlab.ui.control.Label
        VEditField_8                    matlab.ui.control.NumericEditField
        VEditField_8Label               matlab.ui.control.Label
        VEditField_7                    matlab.ui.control.NumericEditField
        VEditField_7Label               matlab.ui.control.Label
        VEditField_6                    matlab.ui.control.NumericEditField
        VEditField_6Label               matlab.ui.control.Label
        VEditField_5                    matlab.ui.control.NumericEditField
        VEditField_5Label               matlab.ui.control.Label
        VBatEditField_2                 matlab.ui.control.NumericEditField
        VBatEditField_2Label            matlab.ui.control.Label
        IntTempEditField_2              matlab.ui.control.NumericEditField
        IntTempEditField_2Label         matlab.ui.control.Label
        Temp3EditField_2                matlab.ui.control.NumericEditField
        Temp3EditField_2Label           matlab.ui.control.Label
        Temp2EditField_2                matlab.ui.control.NumericEditField
        Temp2EditField_2Label           matlab.ui.control.Label
        Temp1EditField_2                matlab.ui.control.NumericEditField
        Temp1EditField_2Label           matlab.ui.control.Label
        mNLPPanel                       matlab.ui.container.Panel
        BatMonEditField                 matlab.ui.control.NumericEditField
        BatMonEditFieldLabel            matlab.ui.control.Label
        VEditField_4                    matlab.ui.control.NumericEditField
        VEditField_4Label               matlab.ui.control.Label
        VEditField_3                    matlab.ui.control.NumericEditField
        VEditField_3Label               matlab.ui.control.Label
        VEditField_2                    matlab.ui.control.NumericEditField
        VEditField_2Label               matlab.ui.control.Label
        VEditField                      matlab.ui.control.NumericEditField
        VEditFieldLabel                 matlab.ui.control.Label
        VBatEditField                   matlab.ui.control.NumericEditField
        VBatEditFieldLabel              matlab.ui.control.Label
        IntTempEditField                matlab.ui.control.NumericEditField
        IntTempEditFieldLabel           matlab.ui.control.Label
        Temp3EditField                  matlab.ui.control.NumericEditField
        Temp3EditFieldLabel             matlab.ui.control.Label
        Temp2EditField                  matlab.ui.control.NumericEditField
        Temp2EditFieldLabel             matlab.ui.control.Label
        Temp1EditField                  matlab.ui.control.NumericEditField
        Temp1EditFieldLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        fileName = ''; % name of UDP file for reading data
        filePath = ''; % path to UDP file for reading data

        mapData = [];
        latlim = [];
        lonlim = [];
        isReading = false;  % reading data from file/UDP stream ?
        fid_r = [];
        isPaused = false;
        isWriting = false;  % writing data to a file?
        writeFile = [];
        fid_w = [];
%         BytesSaved = 0;
        writetime = 0;
    end

    methods (Access = private)

        function stopReading(app)
            app.isReading = false;
            app.Lamp.Color = 'red';
            app.CollectDataSwitch.Value = 'Off';
            app.PlotUpdateRateHzEditField.Editable = "on";
            app.PlotWidthSecondsEditField.Editable = "on";
            app.LoadGPSMapButton.Enable = "on";
            app.SelectFileButton.Enable = "on";
            app.ReadModeSwitch.Enable = "on";
            app.LocalHostEditField.Editable = "on";
            app.LocalPortEditField.Editable = "on";
            app.InstrSelectDropDown.Enable = "on";
            if ~isempty(app.fid_r)
                fclose(app.fid_r);
            end
            app.fid_r = [];
            clear u;
        end

        function stopWriting(app)
            app.isWriting = false;
            app.WriteFileNameEditField.Editable = "on";
            app.WriteEnableSwitch.Value = "Off";
            if ~isempty(app.fid_w)
                fclose(app.fid_w);
            end
            app.fid_w = [];
            app.Lamp_2.Color = "red";
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CollectDataSwitch
        function CollectDataSwitchValueChanged(app, event)


            if (strcmp(app.CollectDataSwitch.Value,"On") == 1)

                %% Setup

                % Close all figures with an empty name: The effect is to
                % close all figures except the main UIFigure
                h = findall(groot,'Type','figure','Name','');
                close(h);
                clear h;

                % Clear display edit fields
                app.Temp1EditField_4.Value = 0;
                app.Temp2EditField_4.Value = 0;
                app.Temp3EditField_4.Value = 0;
                app.IntTempEditField_4.Value = 0;
                app.VBatEditField_4.Value = 0;
                app.VEditField_13.Value = 0;
                app.VEditField_14.Value = 0;
                app.VEditField_15.Value = 0;
                app.VEditField_16.Value = 0;
                app.BatMonEditField_4.Value = 0;

                app.Temp1EditField_2.Value = 0;
                app.Temp2EditField_2.Value = 0;
                app.Temp3EditField_2.Value = 0;
                app.IntTempEditField_2.Value = 0;
                app.VBatEditField_2.Value = 0;
                app.VEditField_5.Value = 0;
                app.VEditField_6.Value = 0;
                app.VEditField_7.Value = 0;
                app.VEditField_8.Value = 0;
                app.BatMonEditField_2.Value = 0;

                app.Temp1EditField.Value = 0;
                app.Temp2EditField.Value = 0;
                app.Temp3EditField.Value = 0;
                app.IntTempEditField.Value = 0;
                app.VBatEditField.Value = 0;
                app.VEditField.Value = 0;
                app.VEditField_2.Value = 0;
                app.VEditField_3.Value = 0;
                app.VEditField_4.Value = 0;
                app.BatMonEditField.Value = 0;

                app.Temp1EditField_3.Value = 0;
                app.Temp2EditField_3.Value = 0;
                app.Temp3EditField_3.Value = 0;
                app.IntTempEditField_3.Value = 0;
                app.VBatEditField_3.Value = 0;
                app.VEditField_9.Value = 0;
                app.VEditField_10.Value = 0;
                app.VEditField_11.Value = 0;
                app.VEditField_12.Value = 0;
                app.BatMonEditField_3.Value = 0;
                app.DigTempEditField.Value = 0;

                app.LatitudedegEditField.Value = 0;
                app.LongitudedegEditField.Value = 0;
                app.AltitudekmEditField.Value = 0;
                app.vEastmsEditField.Value = 0;
                app.vNorthmsEditField.Value = 0;
                app.vUpmsEditField.Value = 0;
                app.HorzSpeedmsEditField.Value = 0;
                app.NumSatsEditField.Value = 0;

                app.PlotUpdateRateHzEditField.Editable = "off";
                app.PlotWidthSecondsEditField.Editable = "off";
                app.LoadGPSMapButton.Enable = "off";
                app.SelectFileButton.Enable = "off";
                app.ReadModeSwitch.Enable = "off";
                app.LocalHostEditField.Editable = "off";
                app.LocalPortEditField.Editable = "off";
                app.InstrSelectDropDown.Enable = "off";


                %
                if (strcmp(app.ReadModeSwitch.Value,"File") && isempty(app.fileName))
                    stopReading(app);
                    disp("No file selected for reading. Please select a file.")
                else
                    % Instrument Select
                    if strcmp(app.InstrSelectDropDown.Value,"EFP (128)")
                        instrMode = 0;
                        % Flight E-box Identifiers
                        EFP_ID = bin2dec('1000 0001');
                        EFP_ID = [bitshift(EFP_ID,-4), bitand(EFP_ID,hex2dec('F'))];
                    
                        mNLP_ID = bin2dec('0001 0011');
                        ACC_ID = bin2dec('0011 0010');
                    else
                        instrMode = 1;
                        PIP_ID = bin2dec('0010 0001');
                        mNLP_ID = bin2dec('0001 0110');  % changed ID from 0101 to 0110 for swapping with spare (10/28/22)
                        ACC_ID = bin2dec('0011 0001');
                    end

                    if strcmp(app.ReadModeSwitch.Value,"File")
                        % Read from file
                        readmode = 0;
                        % Open file for reading
                        try
                            app.fid_r = fopen([app.filePath,app.fileName]);
                            app.isReading = true;
                            app.Lamp.Color = 'yellow'; % yellow until data starts coming in
                            drawnow;
                        catch e
                            disp(e.message)
                            stopReading(app);
                            drawnow;
                        end
                    else
                        % Read from UDP port
                        readmode = 1;
                        % Setup UDP Object
                        try
                            %                             LocalHost = app.LocalHostEditField.Value;
                            LocalPort = app.LocalPortEditField.Value;
                            %                             u = udpport('byte','IPV4','LocalHost',LocalHost,'LocalPort',LocalPort);
                            u = udpport('byte','IPV4','LocalPort',LocalPort);
                            configureMulticast(u, "226.0.0.1");
                            app.isReading = true;
                            app.Lamp.Color = 'yellow'; % yellow until data starts coming in
                            drawnow;
                        catch e
                            disp(e.message)
                            stopReading(app);
                            drawnow;
                        end
                    end

                    % Playback settings
                    plot_rate = app.PlotUpdateRateHzEditField.Value;
                    if plot_rate < 1
                        plot_rate = 1;
                        app.PlotUpdateRateHzEditField.Value = 1;
                    elseif plot_rate > 10
                        plot_rate = 10;
                        app.PlotUpdateRateHzEditField.Value = 10;
                    end
                    plot_delay = 1/plot_rate;
                    % 5000 minor frames/s plus UDP overhead 44 bytes for every minor frame
                    % UDP bitrate = (40*2 + 44) bytes/minorframe * 8 bits/byte * 5000
                    % minorframes/s = 4.960 Mbps
                    udp_bitrate = 4960000; % bps
                    readlength = ceil(124*5000*plot_delay);
                    % Plot width
                    width_s = app.PlotWidthSecondsEditField.Value;
                    if width_s < 1
                        width_s = 1;
                        app.PlotWidthSecondsEditField.Value = 1;
                    end
                    maxNumPoints = 5000*width_s;

                    %% Fixed values
                    minorframelength = 2*40;
                    HK_LENGTH = 11;

                    % The strategy is to ignore UDP header information and search directly for
                    % frame sync words
                    % FS1 = 0b1111-1110-0110-1011 = 0xFE 0x6B = 254 107
                    % FS2 = 0b0010-1000-0100-0000 = 0x28 0x40 = 40 64
                    SYNC = [64,40,107,254];
                    UDP_HEADER = [0x25,0xEB];

                    % swap the endianness of each 32-bit group for TM matrix
                    kk = reshape(transpose((0:(minorframelength/4)-1)'*4 + (3:-1:0)),1,[]);

                    %% GPS definitions

                    rV_HEADER = double(['r','V','0','2','A']); % 2-byte header, 3-byte body length indicator (fixed)
                    rV_LENGTH = 48; % fixed message length in bytes, including header
                    wgs84 = wgs84Ellipsoid('meter');

                    % lat/lon limits are stored in the map file
                    zLim = [-0.020,150];

                    %% Workspace setup
                    rawdata = zeros(readlength,1);
                    % An array pointer to move the last (incomplete) packet in rawdata
                    % to the beginning.
                    % readlength+1 means there is no "remainder" yet.
                    remainderPtr = readlength+1;

                    % We know that exactly minorframelength+44 bytes exist in a complete packet, so we can use
                    % this to check packet validity
                    packetlength = minorframelength+44;

                    % create variable for storing selected minor frame data in matrix format
                    TM_matrix = zeros(ceil(readlength/packetlength),minorframelength);

                    % Allocate memory for housekeeping parsing buffers
                    buffer_EFP_HK = zeros(ceil(readlength/packetlength/2),1);
                    buffer_PIP_HK = zeros(ceil(readlength/packetlength*2),1);
                    buffer_mNLP_HK = zeros(ceil(readlength/packetlength),1);
                    buffer_ACC_HK = zeros(ceil(readlength/packetlength),1);

                    % Allocate memory for parsed data
                    DATA_EFP = zeros(ceil(readlength/packetlength),3);
                    DATA_EFP_HK = zeros(floor(readlength/packetlength/11/2),11);
                    DATA_PIP = zeros(ceil(readlength/packetlength),2);
                    DATA_PIP_HK = zeros(floor(readlength/packetlength/11*2),11);
                    DATA_mNLP = zeros(ceil(readlength/packetlength),3);
                    DATA_mNLP_HK = zeros(floor(readlength/packetlength/11),11);
                    DATA_ACC = zeros(ceil(readlength/packetlength),3);
                    DATA_ACC_HK = zeros(floor(readlength/packetlength/11),11);
                    DATA_D = zeros(ceil(readlength/packetlength),2);

                    % Digital accelerometer and Temp data comes in at 1/2 rate
                    DIG_ACC_numMinorFrames = 2;
                    DATA_DIG_ACC = zeros(ceil(udp_bitrate/8*plot_delay/packetlength/DIG_ACC_numMinorFrames),4);

                    %% Prepare figures
                    % Have a live plot showing the last 5 seconds of data
                    % 5000 minor frames/s plus UDP overhead 44 bytes for every minor frame
                    % UDP bitrate = (40*2 + 44) bytes/minorframe * 8 bits/byte * 5000
                    % minorframes/s = 4.960 Mbps
                    maxNumPointsDigAcc = floor(maxNumPoints/2); %NPG -- pasted in many places [ctrl-F]
                    maxNumPointsD = floor(maxNumPoints/2);

                    % Create separate variables for storing plot YData
                    DATA_EFP_Y = zeros(maxNumPoints,3);
                    DATA_EFP_HK_Y = zeros(maxNumPoints,11);
                    DATA_PIP_Y = zeros(maxNumPoints,2);
                    DATA_PIP_HK_Y = zeros(maxNumPoints,11);
                    DATA_mNLP_Y = zeros(maxNumPoints,3);
                    DATA_mNLP_HK_Y = zeros(maxNumPoints,11);
                    DATA_ACC_Y = zeros(maxNumPoints,3);
                    DATA_ACC_HK_Y = zeros(maxNumPoints,11);
                    DATA_DIG_ACC_Y = zeros(maxNumPointsDigAcc,4);
                    DATA_D_Y = zeros(maxNumPointsD,2);

                    % GPS
                    % Position and velocity is reported in 2's complement ECEF coordinate system
                    GPS_columns = zeros(ceil(readlength/packetlength),3);
                    GPS_onecolumn = zeros(ceil(readlength/packetlength*3),1);
                    GPS_rV_packets = zeros(ceil(readlength/packetlength/rV_LENGTH),rV_LENGTH);
                    GPS_position_ecef = zeros(ceil(readlength/packetlength/rV_LENGTH),3);
                    GPS_position_geodetic = zeros(maxNumPoints,3);
                    GPS_position_valid = zeros(maxNumPoints,1);
                    GPS_velocity_ecef = zeros(ceil(readlength/packetlength/rV_LENGTH),3);
                    GPS_velocity_ENU = zeros(maxNumPoints,3);
                    GPS_velocity_valid = zeros(maxNumPoints,1);
                    GPS_numSats = zeros(maxNumPoints,1);

                    %NPG
                    lineStyle = '.';
                    ylim24 = 1.1*[-2^23 2^23]; % full range for signed 24-bit counts
                    ylim20 = 1.1*[-2^19 2^19]; % full range for signed 20-bit counts
                    ylim18 = 1.1*[-2^17 2^17]; % full range for signed 18-bit counts
                    ylim12 = 1.1*[-2^11 2^11] + 2^11;

                    if (instrMode == 0)
                        fig1 = figure(1);
                        % fig1.Units = 'normalized';
                        % fig1.Position(1:2) = [0.05,0.60];
                        subplot(2,3,3);
                        mNLP_1_line = plot(1:maxNumPoints,DATA_mNLP_Y(:,1),lineStyle,'MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('mNLP'); hold on; xlim([1,maxNumPoints]); %ylim(ylim24);
                        mNLP_2_line = plot(1:maxNumPoints,DATA_mNLP_Y(:,2),lineStyle,'MarkerSize',3);
                        mNLP_3_line = plot(1:maxNumPoints,DATA_mNLP_Y(:,3),lineStyle,'MarkerSize',3);
                        subplot(2,3,1);
                        EFP_1_line = plot(1:maxNumPoints,DATA_EFP_Y(:,1),lineStyle,'MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('FPP'); hold on; xlim([1,maxNumPoints]); %ylim(ylim20);
                        EFP_3_line = plot(1:maxNumPoints,DATA_EFP_Y(:,3),lineStyle,'MarkerSize',3);
                        subplot(2,3,4);
                        EFP_2_line = plot(1:maxNumPoints,DATA_EFP_Y(:,2),lineStyle,'MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('EFP'); hold on; xlim([1,maxNumPoints]); %ylim(ylim20);
                        subplot(2,3,2);
                        ACC_1_line = plot(1:maxNumPoints,DATA_ACC_Y(:,1),lineStyle,'MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('ACC'); hold on; xlim([1,maxNumPoints]); %ylim(ylim18);
                        ACC_2_line = plot(1:maxNumPoints,DATA_ACC_Y(:,2),lineStyle,'MarkerSize',3);
                        ACC_3_line = plot(1:maxNumPoints,DATA_ACC_Y(:,3),lineStyle,'MarkerSize',3);
                        subplot(2,3,5);
                        DIG_ACC_1_line = plot(1:maxNumPointsDigAcc,DATA_DIG_ACC_Y(:,1),'.','MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('DIG ACC'); hold on; xlim([1,maxNumPointsDigAcc]); %ylim(ylim20);
                        DIG_ACC_2_line = plot(1:maxNumPointsDigAcc,DATA_DIG_ACC_Y(:,2),lineStyle,'MarkerSize',3);
                        DIG_ACC_3_line = plot(1:maxNumPointsDigAcc,DATA_DIG_ACC_Y(:,3),lineStyle,'MarkerSize',3);
                        subplot(2,3,6);
                        D1_line = plot(1:maxNumPointsD,DATA_D_Y(:,1),lineStyle,'MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('Experiment D1/D2'); hold on; xlim([1,maxNumPointsD]); %ylim(ylim12);
                        D2_line = plot(1:maxNumPointsD,DATA_D_Y(:,2),lineStyle,'MarkerSize',3);
                    elseif (instrMode == 1)
                        fig1 = figure(1);
                        % fig1.Units = 'normalized';
                        % fig1.Position(1:2) = [0.05,0.60];
                        subplot(2,3,3);
                        mNLP_1_line = plot(1:maxNumPoints,DATA_mNLP_Y(:,1),lineStyle,'MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('mNLP'); hold on; xlim([1,maxNumPoints]); %ylim(ylim24);
                        mNLP_2_line = plot(1:maxNumPoints,DATA_mNLP_Y(:,2),lineStyle,'MarkerSize',3);
                        mNLP_3_line = plot(1:maxNumPoints,DATA_mNLP_Y(:,3),lineStyle,'MarkerSize',3);
                        subplot(2,3,1);
                        PIP_1_line = plot(1:maxNumPoints,DATA_PIP_Y(:,1),lineStyle,'MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('PIP'); hold on; xlim([1,maxNumPoints]); %ylim(ylim24);
                        PIP_2_line = plot(1:maxNumPoints,DATA_PIP_Y(:,2),lineStyle,'MarkerSize',3);
                        subplot(2,3,2);
                        ACC_1_line = plot(1:maxNumPoints,DATA_ACC_Y(:,1),lineStyle,'MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('ACC'); hold on; xlim([1,maxNumPoints]); %ylim(ylim18);
                        ACC_2_line = plot(1:maxNumPoints,DATA_ACC_Y(:,2),lineStyle,'MarkerSize',3);
                        ACC_3_line = plot(1:maxNumPoints,DATA_ACC_Y(:,3),lineStyle,'MarkerSize',3);
                        subplot(2,3,5);
                        DIG_ACC_1_line = plot(1:maxNumPointsDigAcc,DATA_DIG_ACC_Y(:,1),'.','MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('DIG ACC'); hold on; xlim([1,maxNumPointsDigAcc]); %ylim(ylim20);
                        DIG_ACC_2_line = plot(1:maxNumPointsDigAcc,DATA_DIG_ACC_Y(:,2),lineStyle,'MarkerSize',3);
                        DIG_ACC_3_line = plot(1:maxNumPointsDigAcc,DATA_DIG_ACC_Y(:,3),lineStyle,'MarkerSize',3);
                        subplot(2,3,6);
                        D1_line = plot(1:maxNumPointsD,DATA_D_Y(:,1),lineStyle,'MarkerSize',3); xlabel('Sample Number'); ylabel('Counts'); title('Experiment D1/D2'); hold on; xlim([1,maxNumPointsD]); %ylim(ylim12);
                        D2_line = plot(1:maxNumPointsD,DATA_D_Y(:,2),lineStyle,'MarkerSize',3);
                    end

                    if(~isempty(app.latlim) && ~isempty(app.lonlim) && ~isempty(app.mapData))
                        fig2 = figure(2);
                        %     fig2.Units = 'normalized';
                        %     fig2.Position(1:2) = [0.05,0.10];
                        subplot(1,2,1);
                        imagesc(app.lonlim,app.latlim,flipud(app.mapData)); hold on;
                        map2D_ax = gca; map2D_ax.YDir = 'normal';
                        xlim(app.lonlim); ylim(app.latlim);
                        GPS_map2D_line = plot(GPS_position_geodetic(1:maxNumPoints,2),GPS_position_geodetic(1:maxNumPoints,1),'r.');
                        xlabel('Longitude'); ylabel('Latitude'); title('2D Position');

                        subplot(1,2,2);
                        imagesc(app.lonlim,app.latlim,flipud(app.mapData)); hold on;
                        map3D_ax = gca; map3D_ax.YDir = 'normal'; map3D_ax.View = [-45,45];
                        xlim(app.lonlim); ylim(app.latlim);
                        GPS_map3D_line = plot3(GPS_position_geodetic(1:maxNumPoints,2),GPS_position_geodetic(1:maxNumPoints,1),GPS_position_geodetic(1:maxNumPoints,3),'r.');
                        xlabel('Longitude'); ylabel('Latitude'); zlabel('Altitude (km)'); title('3D Position'); zlim([-0.020,160]);
                    end


                    if (readmode == 1)
                        if exist("u","var")
                            while ((u.NumBytesAvailable < readlength) && (strcmp(app.CollectDataSwitch.Value,"On") == 1))
                                % wait until data starts coming in or user
                                % stops program
                                pause(0.01);
                                fprintf("Listening on UDP port: waiting for data...\n");
                            end
                            % now data has started coming in, or user
                            % stopped the program

                            if (strcmp(app.CollectDataSwitch.Value,"Off") == 1)
                                % if user switched data collection off
                                % during the while loop, stop execution
                                stopReading(app);
                                h = findall(groot,'Type','figure','Name','');
                                close(h);
                            end

                        else
                            stopReading(app);
                            h = findall(groot,'Type','figure','Name','');
                            close(h);
                        end
                    end
                end

                %% Main loop

                disp_message_exp = 0; % display lack of data available
                readtime = tic;
                plot_tic = tic;

                while (app.isReading)

                    % Pause point
                    while (app.isPaused)
                        pause(0.1);
                    end

                    % Reset the remainder pointer if the buffer has been filled with junk data
                    if (remainderPtr == 1)
                        remainderPtr = readlength + 1;
                    end

                    % indexes 1:readlength-remainderPtr+1 are populated by the previous remainder
                    % remainderPtr-1 bytes are to be read from the input buffer
                    if (readmode == 0)
                        [A,count] = fread(app.fid_r,remainderPtr-1);
                        rawdata(readlength-remainderPtr+2:readlength-remainderPtr+1+count) = A;
                        % identify the indices of all elements that match the beginning of the sync words
                        packet_ind = strfind(rawdata(1:readlength-remainderPtr+1+count)',SYNC);
                        num_udp_headers = length(strfind(rawdata',UDP_HEADER));

                    elseif (readmode == 1)
                        rawdata(readlength-remainderPtr+2:readlength) = read(u,remainderPtr-1,"uint8");
                        % identify the indices of all elements that match the beginning of the sync words
                        packet_ind = strfind(rawdata',SYNC);
                        num_udp_headers = length(strfind(rawdata',UDP_HEADER));
                        %                         fprintf("Writing to file %s\n",fopen(app.fid_w))
                        if app.isWriting
                            % write data
                            fwrite(app.fid_w,rawdata(readlength-remainderPtr+2:readlength),'uint8');
%                             app.BytesSaved = app.BytesSaved + length(readlength-remainderPtr+2:readlength);
                        end
                    end

                    if ~isempty(packet_ind)

                        app.Lamp.Color = 'green';

                        if app.isWriting
                            %close and reopen write file
                            %                             fprintf("Writing to file %s\n",fopen(app.fid_w))
                            fclose(app.fid_w);
                            app.fid_w =  fopen(app.writeFile,'a+');
                        end

                        % Remember the index of the last frame sync, as this one can't be checked
                        % using a forward difference. It will roll over to the next batch.
                        remainderPtr = packet_ind(end);

                        % Check that each packet is the expected length.
                        % If the distance from one frame sync to the next is not equal to the
                        % expected packet length, the packet associated with the first frame
                        % sync is dropped.
                        deltaIndex = diff(packet_ind);
                        packet_ind = packet_ind(deltaIndex == packetlength);

                        % use rawdata to do main experiment SFID checking
                        ii = (1:length(packet_ind))';
                        SFID = rawdata(packet_ind(ii) + 6);
                        SFID_diff = diff(SFID);
                        SFID_diff = SFID_diff + 20*(SFID_diff < 0);
                        % For main experiment parsing, don't consider minor frames with
                        % duplicate main experiment SFIDs
                        aa = find(SFID_diff == 1);
                        exp_numpackets = length(aa);

                        %NPG
                        if(isempty(aa))
                            if(disp_message_exp > 100)
                                disp('No valid packets detected in data stream')
                                disp_message_exp = 0;
                            end
                            disp_message_exp = disp_message_exp + 1;
                        else

                            % extract the minor frames of correct length up to remainderPtr and put
                            % them in a matrix
                            ii = packet_ind(packet_ind < remainderPtr);
                            numKAMframes = length(ii);
                            TM_matrix(1:numKAMframes,1:minorframelength) = rawdata(ii' + kk);

                            % Move incomplete packet to front of rawdata
                            rawdata(1:readlength-remainderPtr+1) = rawdata(remainderPtr:readlength);

                            % Reset the remainder pointer if the buffer has been filled with junk data
                            if (remainderPtr == 1)
                                remainderPtr = readlength + 1;
                            end

                            % Fill data buffers
                            % N = word column # in matrix spreadsheet
                            % byte column # = 2*(2+N)-1  if first byte in word
                            %               = 2*(2+N)    if second byte in word
                            ii = (1:exp_numpackets)';
                            DATA_mNLP(ii,1) = 2^16*TM_matrix(aa,2*(2+5)-1) + 2^8*TM_matrix(aa,2*(2+5)) + TM_matrix(aa,2*(2+6)-1);
                            DATA_mNLP(ii,2) = 2^16*TM_matrix(aa,2*(2+6)) + 2^8*TM_matrix(aa,2*(2+7)-1) + TM_matrix(aa,2*(2+7));
                            DATA_mNLP(ii,3) = 2^16*TM_matrix(aa,2*(2+8)-1) + 2^8*TM_matrix(aa,2*(2+8)) + TM_matrix(aa,2*(2+9)-1);
                            buffer_mNLP_HK(ii) = TM_matrix(aa,2*(2+9));
                            if (instrMode == 0)
                                DATA_EFP(ii,1) = 2^12*TM_matrix(aa,2*(2+15)-1) + 2^4*TM_matrix(aa,2*(2+15)) + bitand(bitshift(TM_matrix(aa,2*(2+18)-1),-4),15);
                                DATA_EFP(ii,2) = 2^12*TM_matrix(aa,2*(2+16)-1) + 2^4*TM_matrix(aa,2*(2+16)) + bitand(TM_matrix(aa,2*(2+18)-1),15);
                                DATA_EFP(ii,3) = 2^12*TM_matrix(aa,2*(2+17)-1) + 2^4*TM_matrix(aa,2*(2+17)) + bitand(bitshift(TM_matrix(aa,2*(2+18)),-4),15);
                                buffer_EFP_HK(ii) = bitand(TM_matrix(aa,2*(2+18)),15); % 1 nibble only
                            else
                                DATA_PIP(ii,1) = 2^16*TM_matrix(aa,2*(2+15)-1) + 2^8*TM_matrix(aa,2*(2+15)) + TM_matrix(aa,2*(2+16)-1);
                                DATA_PIP(ii,2) = 2^16*TM_matrix(aa,2*(2+16)) + 2^8*TM_matrix(aa,2*(2+17)-1) + TM_matrix(aa,2*(2+17));
                                buffer_PIP_HK(2*ii-1) = TM_matrix(aa,2*(2+18)-1);
                                buffer_PIP_HK(2*ii) = TM_matrix(aa,2*(2+18));
                            end
                            DATA_ACC(ii,1) = 2^10*TM_matrix(aa,2*(2+25)-1) + 2^2*TM_matrix(aa,2*(2+25)) + bitand(bitshift(TM_matrix(aa,2*(2+28)-1),-6),3);
                            DATA_ACC(ii,2) = 2^10*TM_matrix(aa,2*(2+26)-1) + 2^2*TM_matrix(aa,2*(2+26)) + bitand(bitshift(TM_matrix(aa,2*(2+28)-1),-4),3);
                            DATA_ACC(ii,3) = 2^10*TM_matrix(aa,2*(2+27)-1) + 2^2*TM_matrix(aa,2*(2+27)) + bitand(bitshift(TM_matrix(aa,2*(2+28)-1),-2),3);
                            dig_acc_row1 = find(bitand(TM_matrix(aa,2*(2+28)-1),3)==1);
                            dig_acc_row2 = find(bitand(TM_matrix(aa,2*(2+28)-1),3)==2);
                            DATA_DIG_ACC(1:length(dig_acc_row1),1) = 2^12*TM_matrix(aa(dig_acc_row1),2*(2+28)) + 2^4*TM_matrix(aa(dig_acc_row1),2*(2+29)-1) + bitand(bitshift(TM_matrix(aa(dig_acc_row1),2*(2+30)),-4),15);
                            DATA_DIG_ACC(1:length(dig_acc_row1),2) = 2^12*TM_matrix(aa(dig_acc_row1),2*(2+29)) + 2^4*TM_matrix(aa(dig_acc_row1),2*(2+30)-1) + bitand(TM_matrix(aa(dig_acc_row1),2*(2+30)),15);
                            DATA_DIG_ACC(1:length(dig_acc_row2),3) = 2^12*TM_matrix(aa(dig_acc_row2),2*(2+28)) + 2^4*TM_matrix(aa(dig_acc_row2),2*(2+29)-1) + bitand(bitshift(TM_matrix(aa(dig_acc_row2),2*(2+29)),-4),15);
                            DATA_DIG_ACC(1:length(dig_acc_row2),4) = 2^8*bitand(TM_matrix(aa(dig_acc_row2),2*(2+29)),15) + TM_matrix(aa(dig_acc_row2),2*(2+30)-1);
                            buffer_ACC_HK(1:length(dig_acc_row2)) = TM_matrix(aa(dig_acc_row2),2*(2+30));
                            % For digital accelerometer: note that duplicate or missing frames
                            % will make the lengths of dig_acc_row1 and dig_acc_row2 not equal
                            % in general. This implies that DIG_ACC_1 and DIG_ACC_2 could have
                            % a different number of samples than DIG_ACC_3 and DIG_ACC_T in a
                            % given period. To handle this, simply circle shift by the maximum
                            % of the two lengths for both sets. This may create apparent holes
                            % in the data but will approximately preserve the coincidence of
                            % the two sets. (approximately because both sets in a given sample
                            % period will have the same number of points but the "hole" appears
                            % at the left end of the set rather than wherever it appeared).
                            % A search could be done to find the location of skipped frames and
                            % thus assign a hole in these spots, preserving coincidence
                            % everywhere. But this is more work than it's worth. Note that
                            % duplicate frames are disregarded so the only source of holes
                            % would be skipped frames, which occur less frequently (~.05% of
                            % frames based on Speed Demon tests).
                            dig_acc_numpoints = max([length(dig_acc_row1),length(dig_acc_row2)]);

                            sfid = TM_matrix(aa,2*(2+1));
                            sfid_even = find(mod(sfid,2)==0);
                            sfid_odd = find(mod(sfid,2)==1);
                            % sfid runs 0->19: D1 is even, D2 is odd
                            DATA_D(1:length(sfid_even),1) = (2^8*TM_matrix(aa(sfid_even),2*(2+38)-1) + TM_matrix(aa(sfid_even),2*(2+38)))/2^4;
                            DATA_D(1:length(sfid_odd ),2) = (2^8*TM_matrix(aa(sfid_odd) ,2*(2+38)-1) + TM_matrix(aa(sfid_odd) ,2*(2+38)))/2^4;
                            % The same dilemma we face with DIG_ACC is also faced with
                            % experiment.
                            D_numpoints = max([length(sfid_even),length(sfid_odd)]);

                            % end filling data buffers for main exp

                            if (instrMode == 0)
                                DATA_EFP = DATA_EFP - (DATA_EFP >= 2^19)*2^20;
                            else
                                DATA_PIP = DATA_PIP - (DATA_PIP >= 2^23)*2^24;
                            end
                            DATA_mNLP = DATA_mNLP - (DATA_mNLP >= 2^23)*2^24;
                            DATA_ACC = DATA_ACC - (DATA_ACC >= 2^17)*2^18;
                            DATA_DIG_ACC(:,1:3) = DATA_DIG_ACC(:,1:3) - (DATA_DIG_ACC(:,1:3) >= 2^19)*2^20;

                            % Update main instrument data plots
                            if (instrMode == 0)
                                DATA_EFP_Y = circshift(DATA_EFP_Y,-exp_numpackets);
                                DATA_EFP_Y((maxNumPoints-exp_numpackets+1):maxNumPoints,:) = DATA_EFP(1:exp_numpackets,:);
                            else
                                DATA_PIP_Y = circshift(DATA_PIP_Y,-exp_numpackets);
                                DATA_PIP_Y((maxNumPoints-exp_numpackets+1):maxNumPoints,:) = DATA_PIP(1:exp_numpackets,:);
                            end
                            DATA_mNLP_Y = circshift(DATA_mNLP_Y,-exp_numpackets);
                            DATA_ACC_Y = circshift(DATA_ACC_Y,-exp_numpackets);
                            DATA_DIG_ACC_Y = circshift(DATA_DIG_ACC_Y,-dig_acc_numpoints);
                            DATA_D_Y = circshift(DATA_D_Y,-D_numpoints);

                            DATA_mNLP_Y((maxNumPoints-exp_numpackets+1):maxNumPoints,:) = DATA_mNLP(1:exp_numpackets,:);
                            DATA_ACC_Y((maxNumPoints-exp_numpackets+1):maxNumPoints,:) = DATA_ACC(1:exp_numpackets,:);
                            DATA_DIG_ACC_Y((maxNumPointsDigAcc-dig_acc_numpoints+1):(maxNumPointsDigAcc-length(dig_acc_row1)),1:2) = NaN;
                            DATA_DIG_ACC_Y((maxNumPointsDigAcc-dig_acc_numpoints+1):(maxNumPointsDigAcc-length(dig_acc_row2)),3:4) = NaN;
                            DATA_DIG_ACC_Y((maxNumPointsDigAcc-length(dig_acc_row1)+1):maxNumPointsDigAcc,1:2) = DATA_DIG_ACC(1:length(dig_acc_row1),1:2);
                            DATA_DIG_ACC_Y((maxNumPointsDigAcc-length(dig_acc_row2)+1):maxNumPointsDigAcc,3) = DATA_DIG_ACC(1:length(dig_acc_row2),3);

                            if (strcmp(app.HousekeepingSwitch.Value,"Units"))
                                DATA_DIG_ACC_Y((maxNumPointsDigAcc-length(dig_acc_row2)+1):maxNumPointsDigAcc,4) = (-DATA_DIG_ACC(1:length(dig_acc_row2),4)+2078.25) / 9.05; % source: ADXL datasheet
                            else
                                DATA_DIG_ACC_Y((maxNumPointsDigAcc-length(dig_acc_row2)+1):maxNumPointsDigAcc,4) = DATA_DIG_ACC(1:length(dig_acc_row2),4);
                            end
                            DATA_D_Y((maxNumPointsD-D_numpoints+1):(maxNumPointsD-length(sfid_even)),1) = NaN;
                            DATA_D_Y((maxNumPointsD-D_numpoints+1):(maxNumPointsD-length(sfid_odd )),2) = NaN;
                            DATA_D_Y((maxNumPointsD-length(sfid_even)+1):maxNumPointsD,1) = DATA_D(1:length(sfid_even),1);
                            DATA_D_Y((maxNumPointsD-length(sfid_odd )+1):maxNumPointsD,2) = DATA_D(1:length(sfid_odd ),2);

                            % GPS
                            try
                                GPS_columns(ii,1) = 2^8*TM_matrix(aa,2*(2+2)-1) + TM_matrix(aa,2*(2+2));
                                GPS_columns(ii,2) = 2^8*TM_matrix(aa,2*(2+12)-1) + TM_matrix(aa,2*(2+12));
                                GPS_columns(ii,3) = 2^8*TM_matrix(aa,2*(2+22)-1) + TM_matrix(aa,2*(2+22));
                                GPS_columns(ii,4) = 2^8*TM_matrix(aa,2*(2+32)-1) + TM_matrix(aa,2*(2+32));

                                GPS_onecolumn(1:exp_numpackets*4,1) = reshape(transpose(GPS_columns(ii,1:4)),exp_numpackets*4,1);
                                GPS_stream = bitshift(GPS_onecolumn(logical(bitand(GPS_onecolumn,128))),-8);

                                GPS_rV = strfind(GPS_stream',rV_HEADER);
                                if ~isempty(GPS_rV)
                                    if (GPS_rV(end) + rV_LENGTH > length(GPS_stream))
                                        GPS_rV = GPS_rV(1:end-1);
                                    end
                                    % Calculate the CRC and compare with the reported CRC checksum
                                    numRVpackets = length(GPS_rV);
                                    GPS_rV_packets(1:numRVpackets,:) = reshape(GPS_stream(GPS_rV' + (0:rV_LENGTH-1)),numRVpackets,rV_LENGTH);
                                    GPS_rV_crc = zeros(length(GPS_rV),2);
                                    for ii = 1:length(GPS_rV)
                                        GPS_rV_crc(ii,1) = crc16_Dec(GPS_rV_packets(ii,1:rV_LENGTH-3));
                                        GPS_rV_crc(ii,2) = 256*GPS_rV_packets(ii,rV_LENGTH-1) + GPS_rV_packets(ii,rV_LENGTH-2);
                                    end
                                    GPS_rV = GPS_rV(GPS_rV_crc(:,1) == GPS_rV_crc(:,2));
                                    numRVpackets = length(GPS_rV);

                                    if ~isempty(GPS_rV) % if there are any valid packets
                                        GPS_position_geodetic = circshift(GPS_position_geodetic,-numRVpackets);
                                        GPS_position_valid = circshift(GPS_position_valid, -numRVpackets);
                                        GPS_velocity_ENU = circshift(GPS_velocity_ENU, -numRVpackets);
                                        GPS_velocity_valid = circshift(GPS_velocity_valid, -numRVpackets);
                                        GPS_numSats = circshift(GPS_numSats, -numRVpackets);

                                        % extract GPS information
                                        GPS_rV_packets(1:numRVpackets,:) = reshape(GPS_stream(GPS_rV' + (0:rV_LENGTH-1)),numRVpackets,rV_LENGTH);
                                        GPS_position_valid(maxNumPoints-numRVpackets+1:maxNumPoints) = logical(bitand(GPS_rV_packets(1:numRVpackets,16),128));
                                        GPS_velocity_valid(maxNumPoints-numRVpackets+1:maxNumPoints) = logical(bitand(GPS_rV_packets(1:numRVpackets,15),128));

                                        GPS_position_ecef(1:numRVpackets,1:3) = (2^32*GPS_rV_packets(1:numRVpackets,[13,21,29]) + 2^24*GPS_rV_packets(1:numRVpackets,[12,20,28]) + 2^16*GPS_rV_packets(1:numRVpackets,[11,19,27]) + 2^8*GPS_rV_packets(1:numRVpackets,[10,18,26]) + GPS_rV_packets(1:numRVpackets,[17,25,33]) - (GPS_rV_packets(1:numRVpackets,[13,21,29]) >= 128)*2^40) / 10000; % meters signed (ECEF)
                                        [lat,lon,alt] = ecef2geodetic(wgs84,GPS_position_ecef(1:numRVpackets,1),GPS_position_ecef(1:numRVpackets,2),GPS_position_ecef(1:numRVpackets,3));
                                        GPS_position_geodetic(maxNumPoints-numRVpackets+1:maxNumPoints,1:3) = [lat,lon,alt/1000]; % [lat] = [lon] = degrees, [alt/1000] = km

                                        GPS_velocity_ecef(1:numRVpackets,1:3) = (2^20*GPS_rV_packets(1:numRVpackets,[37,41,45]) + 2^12*GPS_rV_packets(1:numRVpackets,[36,40,44]) + 2^4*GPS_rV_packets(1:numRVpackets,[35,39,43]) + bitand(GPS_rV_packets(1:numRVpackets,[34,38,42]),double([0xF0,0xF0,0xF0])) - (GPS_rV_packets(1:numRVpackets,[37,41,45]) >= 128)*2^28) / 10000; % meters/s signed (ECEF)
                                        [vEast,vNorth,vUp] = ecef2enuv(GPS_velocity_ecef(1:numRVpackets,1),GPS_velocity_ecef(1:numRVpackets,2),GPS_velocity_ecef(1:numRVpackets,3),lat,lon);
                                        GPS_velocity_ENU(maxNumPoints-numRVpackets+1:maxNumPoints,1:3) = [vEast,vNorth,vUp];

                                        GPS_numSats(maxNumPoints-numRVpackets+1:maxNumPoints) = bitand(GPS_rV_packets(1:numRVpackets,16),31); % 0b 0001 1111 = 31 (decimal)
                                    end
                                end
                            catch ME
                                fprintf("Error in GPS parsing:\n")  
                                warning(ME.message);
                            end
                            % end GPS


                            if (toc(plot_tic) >= plot_delay)
                                plot_tic = tic;

                                % Figure 1
                                if (instrMode == 0)
                                    set(EFP_1_line,'YData',DATA_EFP_Y(:,1));
                                    set(EFP_2_line,'YData',DATA_EFP_Y(:,2));
                                    set(EFP_3_line,'YData',DATA_EFP_Y(:,3));
                                else
                                    set(PIP_1_line,'YData',DATA_PIP_Y(:,1));
                                    set(PIP_2_line,'YData',DATA_PIP_Y(:,2));
                                end
                                set(mNLP_1_line,'YData',DATA_mNLP_Y(:,1));
                                set(mNLP_2_line,'YData',DATA_mNLP_Y(:,2));
                                set(mNLP_3_line,'YData',DATA_mNLP_Y(:,3));
                                set(ACC_1_line,'YData',DATA_ACC_Y(:,1));
                                set(ACC_2_line,'YData',DATA_ACC_Y(:,2));
                                set(ACC_3_line,'YData',DATA_ACC_Y(:,3));
                                set(DIG_ACC_1_line,'YData',DATA_DIG_ACC_Y(:,1));
                                set(DIG_ACC_2_line,'YData',DATA_DIG_ACC_Y(:,2));
                                set(DIG_ACC_3_line,'YData',DATA_DIG_ACC_Y(:,3));
                                set(D1_line,'YData',DATA_D_Y(:,1));
                                set(D2_line,'YData',DATA_D_Y(:,2));

                                % Figure 2
                                if(~isempty(app.latlim) && ~isempty(app.lonlim) && ~isempty(app.mapData))
                                    set(GPS_map2D_line,'XData',GPS_position_geodetic(1:maxNumPoints,2),'YData',GPS_position_geodetic(1:maxNumPoints,1));
                                    set(GPS_map3D_line,'XData',GPS_position_geodetic(1:maxNumPoints,2),'YData',GPS_position_geodetic(1:maxNumPoints,1),'ZData',GPS_position_geodetic(1:maxNumPoints,3));
                                end

                                % Housekeeping parsing - must find the BOARD ID to locate beginning of
                                % HK loop. Must do this process for each box. Note that this approach
                                % forgoes remainder buffering at the expense of dropping some
                                % HK measurements.

                                if (instrMode == 0)
                                    % EFP
                                    EFP_HK_index = strfind(buffer_EFP_HK',EFP_ID);
                                    EFP_HK_index = EFP_HK_index(diff(EFP_HK_index) == 2*HK_LENGTH);
                                    if ~isempty(EFP_HK_index)
                                        % Extract all valid HK data
                                        ii = 1:length(EFP_HK_index);
                                        DATA_EFP_HK(ii,:) = 2^4*buffer_EFP_HK(EFP_HK_index' + (0:2:2*HK_LENGTH-2)) + ...
                                            buffer_EFP_HK(EFP_HK_index' + (1:2:2*HK_LENGTH-1));
                                        % Add HK data to plotting buffer
                                        DATA_EFP_HK_Y = circshift(DATA_EFP_HK_Y,-length(EFP_HK_index));
                                        DATA_EFP_HK_Y((maxNumPoints-length(EFP_HK_index)+1):maxNumPoints,:) = DATA_EFP_HK(ii,:);
                                    end
                                else
                                    % PIP
                                    PIP_HK_index = strfind(buffer_PIP_HK',PIP_ID);
                                    PIP_HK_index = PIP_HK_index(diff(PIP_HK_index) == HK_LENGTH);
                                    if ~isempty(PIP_HK_index)
                                        % Extract all valid HK data
                                        ii = 1:length(PIP_HK_index);
                                        DATA_PIP_HK(ii,:) = buffer_PIP_HK(PIP_HK_index' + (0:HK_LENGTH-1));
                                        % Add HK data to plotting buffer
                                        DATA_PIP_HK_Y = circshift(DATA_PIP_HK_Y,-length(PIP_HK_index));
                                        DATA_PIP_HK_Y((maxNumPoints-length(PIP_HK_index)+1):maxNumPoints,:) = DATA_PIP_HK(ii,:);
                                    end
                                end

                                % mNLP
                                mNLP_HK_index = strfind(buffer_mNLP_HK',mNLP_ID);
                                mNLP_HK_index = mNLP_HK_index(diff(mNLP_HK_index) == HK_LENGTH);
                                if ~isempty(mNLP_HK_index)
                                    % Extract all valid HK data
                                    ii = 1:length(mNLP_HK_index);
                                    DATA_mNLP_HK(ii,:) = buffer_mNLP_HK(mNLP_HK_index' + (0:HK_LENGTH-1));
                                    % Add HK data to plotting buffer
                                    DATA_mNLP_HK_Y = circshift(DATA_mNLP_HK_Y,-length(mNLP_HK_index));
                                    DATA_mNLP_HK_Y((maxNumPoints-length(mNLP_HK_index)+1):maxNumPoints,:) = DATA_mNLP_HK(ii,:);
                                end

                                % ACC
                                ACC_HK_index = strfind(buffer_ACC_HK',ACC_ID);
                                ACC_HK_index = ACC_HK_index(diff(ACC_HK_index) == HK_LENGTH);
                                if ~isempty(ACC_HK_index)
                                    % Extract all valid HK data
                                    ii = 1:length(ACC_HK_index);
                                    DATA_ACC_HK(ii,:) = buffer_ACC_HK(ACC_HK_index' + (0:HK_LENGTH-1));
                                    % Add HK data to plotting buffer
                                    DATA_ACC_HK_Y = circshift(DATA_ACC_HK_Y,-length(ACC_HK_index));
                                    DATA_ACC_HK_Y((maxNumPoints-length(ACC_HK_index)+1):maxNumPoints,:) = DATA_ACC_HK(ii,:);
                                end

                                % Convert HK counts to temperatures and voltages
                                if strcmp(app.HousekeepingSwitch.Value,"Units")
                                    if (instrMode == 0)
                                        DATA_EFP_HK_Y((maxNumPoints-length(EFP_HK_index)+1):maxNumPoints,2:5) = -76.9231* (DATA_EFP_HK(1:length(EFP_HK_index),2:5) *2.5/256 - 0.5*2.5/256) + 202.54;
                                        DATA_EFP_HK_Y((maxNumPoints-length(EFP_HK_index)+1):maxNumPoints,6:11) = [16,6.15,7.329,3,2,2].*(DATA_EFP_HK(1:length(EFP_HK_index),6:11) *2.5/256 - 0.5*2.5/256) + [0,-16.88,0,0,0,0];
                                    else
                                        DATA_PIP_HK_Y((maxNumPoints-length(PIP_HK_index)+1):maxNumPoints,2:5) = -76.9231* (DATA_PIP_HK(1:length(PIP_HK_index),2:5) *2.5/256 - 0.5*2.5/256) + 202.54;
                                        DATA_PIP_HK_Y((maxNumPoints-length(PIP_HK_index)+1):maxNumPoints,6:11) = [16,6.15,7.329,3,2,2].*(DATA_PIP_HK(1:length(PIP_HK_index),6:11) *2.5/256 - 0.5*2.5/256) + [0,-16.88,0,0,0,0];
                                    end
                                    DATA_mNLP_HK_Y((maxNumPoints-length(mNLP_HK_index)+1):maxNumPoints,2:5) = -76.9231* (DATA_mNLP_HK(1:length(mNLP_HK_index),2:5) *2.5/256 - 0.5*2.5/256) + 202.54;
                                    DATA_mNLP_HK_Y((maxNumPoints-length(mNLP_HK_index)+1):maxNumPoints,6:11) = [16,6.15,7.329,3,2,2].*(DATA_mNLP_HK(1:length(mNLP_HK_index),6:11) *2.5/256 - 0.5*2.5/256) + [0,-16.88,0,0,0,0];
                                    DATA_ACC_HK_Y((maxNumPoints-length(ACC_HK_index)+1):maxNumPoints,2:5) = -76.9231* (DATA_ACC_HK(1:length(ACC_HK_index),2:5) *2.5/256 - 0.5*2.5/256) + 202.54;
                                    DATA_ACC_HK_Y((maxNumPoints-length(ACC_HK_index)+1):maxNumPoints,6:11) = [16,6.15,7.329,3,2,2].*(DATA_ACC_HK(1:length(ACC_HK_index),6:11) *2.5/256 - 0.5*2.5/256) + [0,-16.88,0,0,0,0];
                                end

                                % end HK parsing

                                % HK table - average up to the last 10 points

                                numHKPoints = min(10,exp_numpackets);
                                HK_index_range = maxNumPoints-numHKPoints+1:maxNumPoints;
                                HK_index_rangeDigAcc = maxNumPointsDigAcc-numHKPoints+1:maxNumPointsDigAcc;

                                if (instrMode == 0)
                                    app.Temp1EditField_4.Value = mean(DATA_EFP_HK_Y(HK_index_range,2));
                                    app.Temp2EditField_4.Value = mean(DATA_EFP_HK_Y(HK_index_range,3));
                                    app.Temp3EditField_4.Value = mean(DATA_EFP_HK_Y(HK_index_range,4));
                                    app.IntTempEditField_4.Value = mean(DATA_EFP_HK_Y(HK_index_range,5));
                                    app.VBatEditField_4.Value = mean(DATA_EFP_HK_Y(HK_index_range,6));
                                    app.VEditField_13.Value = mean(DATA_EFP_HK_Y(HK_index_range,7));
                                    app.VEditField_14.Value = mean(DATA_EFP_HK_Y(HK_index_range,8));
                                    app.VEditField_15.Value = mean(DATA_EFP_HK_Y(HK_index_range,9));
                                    app.VEditField_16.Value = mean(DATA_EFP_HK_Y(HK_index_range,10));
                                    app.BatMonEditField_4.Value = mean(DATA_EFP_HK_Y(HK_index_range,11));
                                else
                                    app.Temp1EditField_2.Value = mean(DATA_PIP_HK_Y(HK_index_range,2));
                                    app.Temp2EditField_2.Value = mean(DATA_PIP_HK_Y(HK_index_range,3));
                                    app.Temp3EditField_2.Value = mean(DATA_PIP_HK_Y(HK_index_range,4));
                                    app.IntTempEditField_2.Value = mean(DATA_PIP_HK_Y(HK_index_range,5));
                                    app.VBatEditField_2.Value = mean(DATA_PIP_HK_Y(HK_index_range,6));
                                    app.VEditField_5.Value = mean(DATA_PIP_HK_Y(HK_index_range,7));
                                    app.VEditField_6.Value = mean(DATA_PIP_HK_Y(HK_index_range,8));
                                    app.VEditField_7.Value = mean(DATA_PIP_HK_Y(HK_index_range,9));
                                    app.VEditField_8.Value = mean(DATA_PIP_HK_Y(HK_index_range,10));
                                    app.BatMonEditField_2.Value = mean(DATA_PIP_HK_Y(HK_index_range,11));
                                end

                                app.Temp1EditField.Value = mean(DATA_mNLP_HK_Y(HK_index_range,2));
                                app.Temp2EditField.Value = mean(DATA_mNLP_HK_Y(HK_index_range,3));
                                app.Temp3EditField.Value = mean(DATA_mNLP_HK_Y(HK_index_range,4));
                                app.IntTempEditField.Value = mean(DATA_mNLP_HK_Y(HK_index_range,5));
                                app.VBatEditField.Value = mean(DATA_mNLP_HK_Y(HK_index_range,6));
                                app.VEditField.Value = mean(DATA_mNLP_HK_Y(HK_index_range,7));
                                app.VEditField_2.Value = mean(DATA_mNLP_HK_Y(HK_index_range,8));
                                app.VEditField_3.Value = mean(DATA_mNLP_HK_Y(HK_index_range,9));
                                app.VEditField_4.Value = mean(DATA_mNLP_HK_Y(HK_index_range,10));
                                app.BatMonEditField.Value = mean(DATA_mNLP_HK_Y(HK_index_range,11));

                                app.Temp1EditField_3.Value = mean(DATA_ACC_HK_Y(HK_index_range,2));
                                app.Temp2EditField_3.Value = mean(DATA_ACC_HK_Y(HK_index_range,3));
                                app.Temp3EditField_3.Value = mean(DATA_ACC_HK_Y(HK_index_range,4));
                                app.IntTempEditField_3.Value = mean(DATA_ACC_HK_Y(HK_index_range,5));
                                app.VBatEditField_3.Value = mean(DATA_ACC_HK_Y(HK_index_range,6));
                                app.VEditField_9.Value = mean(DATA_ACC_HK_Y(HK_index_range,7));
                                app.VEditField_10.Value = mean(DATA_ACC_HK_Y(HK_index_range,8));
                                app.VEditField_11.Value = mean(DATA_ACC_HK_Y(HK_index_range,9));
                                app.VEditField_12.Value = mean(DATA_ACC_HK_Y(HK_index_range,10));
                                app.BatMonEditField_3.Value = mean(DATA_ACC_HK_Y(HK_index_range,11));
                                dig_acc_temp = mean(DATA_DIG_ACC_Y(HK_index_rangeDigAcc,4),'omitnan');
                                if ~isnan(dig_acc_temp)
                                    app.DigTempEditField.Value = dig_acc_temp;
                                end

                                % GPS display values
                                app.LatitudedegEditField.Value = GPS_position_geodetic(end,1);
                                app.LongitudedegEditField.Value = GPS_position_geodetic(end,2);
                                app.AltitudekmEditField.Value = GPS_position_geodetic(end,3);
                                app.vEastmsEditField.Value = GPS_velocity_ENU(end,1);
                                app.vNorthmsEditField.Value = GPS_velocity_ENU(end,2);
                                app.vUpmsEditField.Value = GPS_velocity_ENU(end,3);
                                app.HorzSpeedmsEditField.Value = sqrt(GPS_velocity_ENU(end,1)^2 + GPS_velocity_ENU(end,2)^2);
                                app.NumSatsEditField.Value = GPS_numSats(end);

                                %npg
                                if(exist('u','var'))
                                    app.BytesinBufferEditField.Value = u.NumBytesAvailable;
                                end
                                
                                % Calculate and display the time elapsed
                                % since switching "Collect Data"
                                t_read = toc(readtime);
                                ss = mod(t_read,60);
                                mm = floor(t_read/60);
                                hh = floor(t_read/3600);
                                app.ReadSessionTimeEditField.Value = sprintf("%d:%2d:%2.3f",hh,mm,ss);

                                if app.isWriting
                                    t_write = toc(app.writetime);
                                    ss = mod(t_write,60);
                                    mm = floor(t_write/60);
                                    hh = floor(t_write/3600);
                                    app.WriteSessionTimeEditField.Value = sprintf("%d:%2d:%2.3f",hh,mm,ss);
                                else
                                    app.WriteSessionTimeEditField.Value = "0:00:00.000";
                                end

                                % Display the number of kilobytes saved
                                % since started writing data
%                                 app.KBSavedEditField.Value = floor(app.BytesSaved/1024);

                                drawnow limitrate;
                            end

                            % Don't deliberately pause if reading live data. Want code to run
                            % as fast as possible. This pause is only to simulate 'real-time'
                            % reading in file-read mode
                            if (readmode == 0)
                                multiplier = app.PlaybackSpeedxEditField.Value;
                                if (multiplier < .01)
                                    multiplier = .01;
                                    app.PlaybackSpeedxEditField.Value = .01;
                                    % I forbid you to divide by zero
                                end
                                pause(plot_delay/multiplier);
                            end
                        end

                    else
                        % packet_ind is empty, meaning no TM frame sync
                        % occurrences were found in the data stream. Most
                        % likely, UDP stream is on but no TM stream
                        % information is being sent.
                        app.Lamp.Color = 'yellow';
                        fprintf("No TM frame sync found. Is the TM stream on? UDP headers found: %d\n",num_udp_headers);
                        pause(0.01);
                    end
                    % end main loop

                    % Check whether data is still available
                    if (readmode == 1)
                        % Wait here for at most 5 seconds. If no data comes through, exit
                        exit_tic = tic;
                        while ((u.NumBytesAvailable < remainderPtr-1) && (toc(exit_tic) < 5))
                            % do nothing
                        end
                        if ~(toc(exit_tic) < 5)
                            stopWriting(app);
                            stopReading(app);
                            disp("No data received for 5 seconds.. shutting down.")
                        end
                    elseif (readmode == 0)
                        if feof(app.fid_r)
                            stopReading(app);
                            disp("Reached end of file.. shutting down.")
                        end
                    end

                    % Check to see if user has disabled reading
                    if (strcmp(app.CollectDataSwitch.Value,"Off"))
                        stopWriting(app);
                        stopReading(app);
                    end
                end

                toc(readtime)

            else
                % switch turned off
            end

        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [name,path] = uigetfile({'*.udp;*.bin'},'Select a UDP recording','..\');
            if ischar(name)
                app.ReadFileNameEditField.Value = name;
                app.fileName = name;
                app.filePath = path;
            end
            figure(app.UIFigure); % for some reason uigetfile minimizes the UIFigure. This brings it back up
        end

        % Button pushed function: LoadGPSMapButton
        function LoadGPSMapButtonPushed(app, event)
            [newfile,path] = uigetfile('*.mat','Load Map File','map1.mat');
            if newfile == 0
                warning("No map data loaded");
            else
                filename=fullfile(path,newfile);
                load(filename,'ZA','latlim','lonlim');
                app.MapFileNameEditField.Value = newfile;
                app.mapData = ZA;
                app.latlim = latlim; %#ok<ADPROPLC>
                app.lonlim = lonlim; %#ok<ADPROPLC>
            end
            figure(app.UIFigure); % for some reason uigetfile minimizes the UIFigure. This brings it back up
        end

        % Value changed function: WriteEnableSwitch
        function WriteEnableSwitchValueChanged(app, event)
            % Write enable: 0 for no write, 1 for write
            if strcmp(app.WriteEnableSwitch.Value,"On")
                readValue = strcmp(app.ReadModeSwitch.Value,'File'); % 1 for File, 0 for UDP
                if readValue
                    % Don't allow writing if already reading from a file
                    stopWriting(app)
                else
                    % UDP read mode
                    % Open file for writing
                    try
                        app.writetime = tic;
%                         app.BytesSaved = 0;
%                         app.KBSavedEditField.Value = 0;
                        app.writeFile = app.WriteFileNameEditField.Value;
                        app.fid_w = fopen(app.writeFile,'a');
                        fprintf("Opened file %d with name %s\n",app.fid_w,app.writeFile);
                        app.isWriting = true;
                        app.WriteFileNameEditField.Editable = "off";
                        app.Lamp_2.Color = "green";
                    catch ME
                        disp(ME.message);
                        app.fid_w = [];
                        stopWriting(app)
                    end
                end
            else
                stopWriting(app)
            end

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            if value
                % 1 = pressed
                app.PauseButton.Text = "Paused";
                app.isPaused = true;
            else
                % 0 = unpressed
                app.PauseButton.Text = "Pause";
                app.isPaused = false;
            end
        end

        % Value changed function: ReadModeSwitch
        function ReadModeSwitchValueChanged(app, event)
            value = app.ReadModeSwitch.Value;
            if strcmp(value,'File')
                % Enable playback control
                app.PauseButton.Enable = "on";
                app.PlaybackSpeedxEditField.Editable = "on";
                % Disable file writing
                stopWriting(app);
            else
                % Disable playback control
                app.PauseButton.Value = 0;
                app.PauseButton.Text = "Pause";
                app.PauseButton.Enable = "off";
                app.PlaybackSpeedxEditField.Editable = "off";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.mNLPPanel = uipanel(app.UIFigure);
            app.mNLPPanel.Title = 'mNLP';
            app.Temp1EditFieldLabel = uilabel(app.mNLPPanel);
            app.Temp1EditFieldLabel.Text = 'Temp 1';
            app.Temp1EditField = uieditfield(app.mNLPPanel, 'numeric');
            app.Temp1EditField.Editable = 'off';
            app.Temp2EditFieldLabel = uilabel(app.mNLPPanel);
            app.Temp2EditFieldLabel.Text = 'Temp 2';
            app.Temp2EditField = uieditfield(app.mNLPPanel, 'numeric');
            app.Temp2EditField.Editable = 'off';
            app.Temp3EditFieldLabel = uilabel(app.mNLPPanel);
            app.Temp3EditFieldLabel.Enable = 'off';
            app.Temp3EditFieldLabel.Text = 'Temp 3';
            app.Temp3EditField = uieditfield(app.mNLPPanel, 'numeric');
            app.Temp3EditField.Editable = 'off';
            app.Temp3EditField.Enable = 'off';
            app.IntTempEditFieldLabel = uilabel(app.mNLPPanel);
            app.IntTempEditFieldLabel.Enable = 'off';
            app.IntTempEditFieldLabel.Text = 'Int. Temp';
            app.IntTempEditField = uieditfield(app.mNLPPanel, 'numeric');
            app.IntTempEditField.Editable = 'off';
            app.IntTempEditField.Enable = 'off';
            app.VBatEditFieldLabel = uilabel(app.mNLPPanel);
            app.VBatEditFieldLabel.Text = 'V Bat';
            app.VBatEditField = uieditfield(app.mNLPPanel, 'numeric');
            app.VBatEditField.Editable = 'off';
            app.VEditFieldLabel = uilabel(app.mNLPPanel);
            app.VEditFieldLabel.Text = '-12 V';
            app.VEditField = uieditfield(app.mNLPPanel, 'numeric');
            app.VEditField.Editable = 'off';
            app.VEditField_2Label = uilabel(app.mNLPPanel);
            app.VEditField_2Label.Text = '+12 V';
            app.VEditField_2 = uieditfield(app.mNLPPanel, 'numeric');
            app.VEditField_2.Editable = 'off';
            app.VEditField_3Label = uilabel(app.mNLPPanel);
            app.VEditField_3Label.Text = '+5 V';
            app.VEditField_3 = uieditfield(app.mNLPPanel, 'numeric');
            app.VEditField_3.Editable = 'off';
            app.VEditField_4Label = uilabel(app.mNLPPanel);
            app.VEditField_4Label.Text = '+3.3 V';
            app.VEditField_4 = uieditfield(app.mNLPPanel, 'numeric');
            app.VEditField_4.Editable = 'off';
            app.BatMonEditFieldLabel = uilabel(app.mNLPPanel);
            app.BatMonEditFieldLabel.Text = 'Bat Mon';
            app.BatMonEditField = uieditfield(app.mNLPPanel, 'numeric');
            app.BatMonEditField.Editable = 'off';
            app.PIPPanel = uipanel(app.UIFigure);
            app.PIPPanel.Title = 'PIP';
            app.Temp1EditField_2Label = uilabel(app.PIPPanel);
            app.Temp1EditField_2Label.Text = 'Temp 1';
            app.Temp1EditField_2 = uieditfield(app.PIPPanel, 'numeric');
            app.Temp1EditField_2.Editable = 'off';
            app.Temp2EditField_2Label = uilabel(app.PIPPanel);
            app.Temp2EditField_2Label.Text = 'Temp 2';
            app.Temp2EditField_2 = uieditfield(app.PIPPanel, 'numeric');
            app.Temp2EditField_2.Editable = 'off';
            app.Temp3EditField_2Label = uilabel(app.PIPPanel);
            app.Temp3EditField_2Label.Enable = 'off';
            app.Temp3EditField_2Label.Text = 'Temp 3';
            app.Temp3EditField_2 = uieditfield(app.PIPPanel, 'numeric');
            app.Temp3EditField_2.Editable = 'off';
            app.Temp3EditField_2.Enable = 'off';
            app.IntTempEditField_2Label = uilabel(app.PIPPanel);
            app.IntTempEditField_2Label.Enable = 'off';
            app.IntTempEditField_2Label.Text = 'Int. Temp';
            app.IntTempEditField_2 = uieditfield(app.PIPPanel, 'numeric');
            app.IntTempEditField_2.Editable = 'off';
            app.IntTempEditField_2.Enable = 'off';
            app.VBatEditField_2Label = uilabel(app.PIPPanel);
            app.VBatEditField_2Label.Text = 'V Bat';
            app.VBatEditField_2 = uieditfield(app.PIPPanel, 'numeric');
            app.VBatEditField_2.Editable = 'off';
            app.VEditField_5Label = uilabel(app.PIPPanel);
            app.VEditField_5Label.Text = '-12 V';
            app.VEditField_5 = uieditfield(app.PIPPanel, 'numeric');
            app.VEditField_5.Editable = 'off';
            app.VEditField_6Label = uilabel(app.PIPPanel);
            app.VEditField_6Label.Text = '+12 V';
            app.VEditField_6 = uieditfield(app.PIPPanel, 'numeric');
            app.VEditField_6.Editable = 'off';
            app.VEditField_7Label = uilabel(app.PIPPanel);
            app.VEditField_7Label.Text = '+5 V';
            app.VEditField_7 = uieditfield(app.PIPPanel, 'numeric');
            app.VEditField_7.Editable = 'off';
            app.VEditField_8Label = uilabel(app.PIPPanel);
            app.VEditField_8Label.Text = '+3.3 V';
            app.VEditField_8 = uieditfield(app.PIPPanel, 'numeric');
            app.VEditField_8.Editable = 'off';
            app.BatMonEditField_2Label = uilabel(app.PIPPanel);
            app.BatMonEditField_2Label.Text = 'Bat Mon';
            app.BatMonEditField_2 = uieditfield(app.PIPPanel, 'numeric');
            app.BatMonEditField_2.Editable = 'off';
            app.ACCPanel = uipanel(app.UIFigure);
            app.ACCPanel.Title = 'ACC';
            app.Temp1EditField_3Label = uilabel(app.ACCPanel);
            app.Temp1EditField_3Label.Text = 'Temp 1';
            app.Temp1EditField_3 = uieditfield(app.ACCPanel, 'numeric');
            app.Temp1EditField_3.Editable = 'off';
            app.Temp2EditField_3Label = uilabel(app.ACCPanel);
            app.Temp2EditField_3Label.Enable = 'off';
            app.Temp2EditField_3Label.Text = 'Temp 2';
            app.Temp2EditField_3 = uieditfield(app.ACCPanel, 'numeric');
            app.Temp2EditField_3.Editable = 'off';
            app.Temp2EditField_3.Enable = 'off';
            app.Temp3EditField_3Label = uilabel(app.ACCPanel);
            app.Temp3EditField_3Label.Enable = 'off';
            app.Temp3EditField_3Label.Text = 'Temp 3';
            app.Temp3EditField_3 = uieditfield(app.ACCPanel, 'numeric');
            app.Temp3EditField_3.Editable = 'off';
            app.Temp3EditField_3.Enable = 'off';
            app.IntTempEditField_3Label = uilabel(app.ACCPanel);
            app.IntTempEditField_3Label.Enable = 'off';
            app.IntTempEditField_3Label.Text = 'Int. Temp';
            app.IntTempEditField_3 = uieditfield(app.ACCPanel, 'numeric');
            app.IntTempEditField_3.Editable = 'off';
            app.IntTempEditField_3.Enable = 'off';
            app.VBatEditField_3Label = uilabel(app.ACCPanel);
            app.VBatEditField_3Label.Text = 'V Bat';
            app.VBatEditField_3 = uieditfield(app.ACCPanel, 'numeric');
            app.VBatEditField_3.Editable = 'off';
            app.VEditField_9Label = uilabel(app.ACCPanel);
            app.VEditField_9Label.Enable = 'off';
            app.VEditField_9Label.Text = '-12 V';
            app.VEditField_9 = uieditfield(app.ACCPanel, 'numeric');
            app.VEditField_9.Editable = 'off';
            app.VEditField_9.Enable = 'off';
            app.VEditField_10Label = uilabel(app.ACCPanel);
            app.VEditField_10Label.Enable = 'off';
            app.VEditField_10Label.Text = '+12 V';
            app.VEditField_10 = uieditfield(app.ACCPanel, 'numeric');
            app.VEditField_10.Editable = 'off';
            app.VEditField_10.Enable = 'off';
            app.VEditField_11Label = uilabel(app.ACCPanel);
            app.VEditField_11Label.Text = '+5 V';
            app.VEditField_11 = uieditfield(app.ACCPanel, 'numeric');
            app.VEditField_11.Editable = 'off';
            app.VEditField_12Label = uilabel(app.ACCPanel);
            app.VEditField_12Label.Text = '+3.3 V';
            app.VEditField_12 = uieditfield(app.ACCPanel, 'numeric');
            app.VEditField_12.Editable = 'off';
            app.BatMonEditField_3Label = uilabel(app.ACCPanel);
            app.BatMonEditField_3Label.Text = 'Bat Mon';
            app.BatMonEditField_3 = uieditfield(app.ACCPanel, 'numeric');
            app.BatMonEditField_3.Editable = 'off';
            app.DigTempEditFieldLabel = uilabel(app.ACCPanel);
            app.DigTempEditFieldLabel.Text = 'Dig. Temp';
            app.DigTempEditField = uieditfield(app.ACCPanel, 'numeric');
            app.DigTempEditField.Editable = 'off';
            app.GPSPanel = uipanel(app.UIFigure);
            app.GPSPanel.Title = 'GPS';
            app.LatitudedegEditFieldLabel = uilabel(app.GPSPanel);
            app.LatitudedegEditFieldLabel.Text = 'Latitude (deg)';
            app.LatitudedegEditField = uieditfield(app.GPSPanel, 'numeric');
            app.LatitudedegEditField.Editable = 'off';
            app.LongitudedegEditFieldLabel = uilabel(app.GPSPanel);
            app.LongitudedegEditFieldLabel.Text = 'Longitude (deg)';
            app.LongitudedegEditField = uieditfield(app.GPSPanel, 'numeric');
            app.LongitudedegEditField.Editable = 'off';
            app.AltitudekmEditFieldLabel = uilabel(app.GPSPanel);
            app.AltitudekmEditFieldLabel.Text = 'Altitude (km)';
            app.AltitudekmEditField = uieditfield(app.GPSPanel, 'numeric');
            app.AltitudekmEditField.Editable = 'off';
            app.vEastmsEditFieldLabel = uilabel(app.GPSPanel);
            app.vEastmsEditFieldLabel.Text = 'vEast (m/s)';
            app.vEastmsEditField = uieditfield(app.GPSPanel, 'numeric');
            app.vEastmsEditField.Editable = 'off';
            app.vNorthmsEditFieldLabel = uilabel(app.GPSPanel);
            app.vNorthmsEditFieldLabel.Text = 'vNorth (m/s)';
            app.vNorthmsEditField = uieditfield(app.GPSPanel, 'numeric');
            app.vNorthmsEditField.Editable = 'off';
            app.vUpmsEditFieldLabel = uilabel(app.GPSPanel);
            app.vUpmsEditFieldLabel.Text = 'vUp (m/s)';
            app.vUpmsEditField = uieditfield(app.GPSPanel, 'numeric');
            app.vUpmsEditField.Editable = 'off';
            app.HorzSpeedmsEditFieldLabel = uilabel(app.GPSPanel);
            app.HorzSpeedmsEditFieldLabel.Text = 'Horz. Speed (m/s)';
            app.HorzSpeedmsEditField = uieditfield(app.GPSPanel, 'numeric');
            app.HorzSpeedmsEditField.Editable = 'off';
            app.NumSatsEditFieldLabel = uilabel(app.GPSPanel);
            app.NumSatsEditFieldLabel.Text = 'Num Sats';
            app.NumSatsEditField = uieditfield(app.GPSPanel, 'numeric');
            app.NumSatsEditField.Editable = 'off';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'SAIL_Transparent.gif';
            app.EFPPanel = uipanel(app.UIFigure);
            app.EFPPanel.Title = 'EFP';
            app.Temp1EditField_4Label = uilabel(app.EFPPanel);
            app.Temp1EditField_4Label.Text = 'Temp 1';
            app.Temp1EditField_4 = uieditfield(app.EFPPanel, 'numeric');
            app.Temp1EditField_4.Editable = 'off';
            app.Temp2EditField_4Label = uilabel(app.EFPPanel);
            app.Temp2EditField_4Label.Enable = 'off';
            app.Temp2EditField_4Label.Text = 'Temp 2';
            app.Temp2EditField_4 = uieditfield(app.EFPPanel, 'numeric');
            app.Temp2EditField_4.Editable = 'off';
            app.Temp2EditField_4.Enable = 'off';
            app.Temp3EditField_4Label = uilabel(app.EFPPanel);
            app.Temp3EditField_4Label.Enable = 'off';
            app.Temp3EditField_4Label.Text = 'Temp 3';
            app.Temp3EditField_4 = uieditfield(app.EFPPanel, 'numeric');
            app.Temp3EditField_4.Editable = 'off';
            app.Temp3EditField_4.Enable = 'off';
            app.IntTempEditField_4Label = uilabel(app.EFPPanel);
            app.IntTempEditField_4Label.Enable = 'off';
            app.IntTempEditField_4Label.Text = 'Int. Temp';
            app.IntTempEditField_4 = uieditfield(app.EFPPanel, 'numeric');
            app.IntTempEditField_4.Editable = 'off';
            app.IntTempEditField_4.Enable = 'off';
            app.VBatEditField_4Label = uilabel(app.EFPPanel);
            app.VBatEditField_4Label.Text = 'V Bat';
            app.VBatEditField_4 = uieditfield(app.EFPPanel, 'numeric');
            app.VBatEditField_4.Editable = 'off';
            app.VEditField_13Label = uilabel(app.EFPPanel);
            app.VEditField_13Label.Text = '-12 V';
            app.VEditField_13 = uieditfield(app.EFPPanel, 'numeric');
            app.VEditField_13.Editable = 'off';
            app.VEditField_14Label = uilabel(app.EFPPanel);
            app.VEditField_14Label.Text = '+12 V';
            app.VEditField_14 = uieditfield(app.EFPPanel, 'numeric');
            app.VEditField_14.Editable = 'off';
            app.VEditField_15Label = uilabel(app.EFPPanel);
            app.VEditField_15Label.Text = '+5 V';
            app.VEditField_15 = uieditfield(app.EFPPanel, 'numeric');
            app.VEditField_15.Editable = 'off';
            app.VEditField_16Label = uilabel(app.EFPPanel);
            app.VEditField_16Label.Text = '+3.3 V';
            app.VEditField_16 = uieditfield(app.EFPPanel, 'numeric');
            app.VEditField_16.Editable = 'off';
            app.BatMonEditField_4Label = uilabel(app.EFPPanel);
            app.BatMonEditField_4Label.Text = 'Bat Mon';
            app.BatMonEditField_4 = uieditfield(app.EFPPanel, 'numeric');
            app.BatMonEditField_4.Editable = 'off';
            app.FilePlaybackControlPanel = uipanel(app.UIFigure);
            app.FilePlaybackControlPanel.Title = 'File Playback Control';
            app.PauseButton = uibutton(app.FilePlaybackControlPanel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.PlaybackSpeedxEditFieldLabel = uilabel(app.FilePlaybackControlPanel);
            app.PlaybackSpeedxEditFieldLabel.Text = 'Playback Speed x';
            app.PlaybackSpeedxEditField = uieditfield(app.FilePlaybackControlPanel, 'numeric');
            app.PlaybackSpeedxEditField.ValueDisplayFormat = '%11.2f';
            app.PlaybackSpeedxEditField.Value = 1;
            app.SetupPanel = uipanel(app.UIFigure);
            app.SetupPanel.Title = 'Setup';
            app.SelectFileButton = uibutton(app.SetupPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.LoadGPSMapButton = uibutton(app.SetupPanel, 'push');
            app.LoadGPSMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGPSMapButtonPushed, true);
            app.LoadGPSMapButton.Text = 'Load GPS Map';
            app.LocalPortEditFieldLabel = uilabel(app.SetupPanel);
            app.LocalPortEditFieldLabel.Text = 'Local Port';
            app.LocalPortEditField = uieditfield(app.SetupPanel, 'numeric');
            app.LocalPortEditField.Value = 5555;
            app.ReadFileNameEditFieldLabel = uilabel(app.SetupPanel);
            app.ReadFileNameEditFieldLabel.Text = 'Read File Name';
            app.ReadFileNameEditField = uieditfield(app.SetupPanel, 'text');
            app.ReadFileNameEditField.Editable = 'off';
            app.LocalHostEditFieldLabel = uilabel(app.SetupPanel);
            app.LocalHostEditFieldLabel.Text = 'Local Host';
            app.LocalHostEditField = uieditfield(app.SetupPanel, 'text');
            app.MapFileNameEditFieldLabel = uilabel(app.SetupPanel);
            app.MapFileNameEditFieldLabel.Text = 'Map File Name';
            app.MapFileNameEditField = uieditfield(app.SetupPanel, 'text');
            app.MapFileNameEditField.Editable = 'off';
            app.ReadModeSwitchLabel = uilabel(app.SetupPanel);
            app.ReadModeSwitchLabel.Text = 'Read Mode';
            app.ReadModeSwitch = uiswitch(app.SetupPanel, 'slider');
            app.ReadModeSwitch.Items = {'UDP', 'File'};
            app.ReadModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadModeSwitchValueChanged, true);
            app.ReadModeSwitch.Value = 'File';
            app.InstrSelectDropDownLabel = uilabel(app.SetupPanel);
            app.InstrSelectDropDownLabel.Text = 'Instr. Select';
            app.InstrSelectDropDown = uidropdown(app.SetupPanel);
            app.InstrSelectDropDown.Items = {'PIP (127)', 'EFP (128)'};
            app.InstrSelectDropDown.Value = 'PIP (127)';
            app.PlotSettingsPanel = uipanel(app.SetupPanel);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.PlotUpdateRateHzEditFieldLabel = uilabel(app.PlotSettingsPanel);
            app.PlotUpdateRateHzEditFieldLabel.Text = 'Plot Update Rate (Hz)';
            app.PlotUpdateRateHzEditField = uieditfield(app.PlotSettingsPanel, 'numeric');
            app.PlotUpdateRateHzEditField.Value = 5;
            app.PlotWidthSecondsEditFieldLabel = uilabel(app.PlotSettingsPanel);
            app.PlotWidthSecondsEditFieldLabel.Text = 'Plot Width (Seconds)';
            app.PlotWidthSecondsEditField = uieditfield(app.PlotSettingsPanel, 'numeric');
            app.PlotWidthSecondsEditField.Value = 10;
            app.Image = uiimage(app.SetupPanel);
            app.Image.ImageSource = 'plot_legend.png';
            app.GeneralLegendLabel = uilabel(app.SetupPanel);
            app.GeneralLegendLabel.Text = 'General Legend';
            app.LiveControlPanel = uipanel(app.UIFigure);
            app.LiveControlPanel.Title = 'Live Control';
            app.Lamp = uilamp(app.LiveControlPanel);
            app.Lamp_2 = uilamp(app.LiveControlPanel);
            app.WriteFileNameEditFieldLabel = uilabel(app.LiveControlPanel);
            app.WriteFileNameEditFieldLabel.Text = 'Write File Name';
            app.WriteFileNameEditField = uieditfield(app.LiveControlPanel, 'text');
            app.WriteEnableSwitchLabel = uilabel(app.LiveControlPanel);
            app.WriteEnableSwitchLabel.Text = 'Write Enable';
            app.WriteEnableSwitch = uiswitch(app.LiveControlPanel, 'slider');
            app.WriteEnableSwitch.ValueChangedFcn = createCallbackFcn(app, @WriteEnableSwitchValueChanged, true);
            app.CollectDataSwitchLabel = uilabel(app.LiveControlPanel);
            app.CollectDataSwitchLabel.Text = 'Collect Data';
            app.CollectDataSwitch = uiswitch(app.LiveControlPanel, 'slider');
            app.CollectDataSwitch.ValueChangedFcn = createCallbackFcn(app, @CollectDataSwitchValueChanged, true);
            app.HousekeepingSwitchLabel = uilabel(app.LiveControlPanel);
            app.HousekeepingSwitchLabel.Text = 'Housekeeping';
            app.HousekeepingSwitch = uiswitch(app.LiveControlPanel, 'slider');
            app.HousekeepingSwitch.Items = {'Counts', 'Units'};
            app.HousekeepingSwitch.Value = 'Counts';
            app.BytesinBufferEditFieldLabel = uilabel(app.UIFigure);
            app.BytesinBufferEditFieldLabel.Text = 'Bytes in Buffer';
            app.BytesinBufferEditField = uieditfield(app.UIFigure, 'numeric');
            app.BytesinBufferEditField.ValueDisplayFormat = '%i';
            app.BytesinBufferEditField.Editable = 'off';
            app.ReadSessionTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ReadSessionTimeEditFieldLabel.Text = 'Read Session Time';
            app.ReadSessionTimeEditField = uieditfield(app.UIFigure, 'text');
            app.ReadSessionTimeEditField.Editable = 'off';
            app.ReadSessionTimeEditField.Value = '0:00:00.000';
            app.hmsLabel = uilabel(app.UIFigure);
            app.hmsLabel.Text = 'h:m:s';
            app.WriteSessionTimeEditFieldLabel = uilabel(app.UIFigure);
            app.WriteSessionTimeEditFieldLabel.Text = 'Write Session Time';
            app.WriteSessionTimeEditField = uieditfield(app.UIFigure, 'text');
            app.WriteSessionTimeEditField.Editable = 'off';
            app.WriteSessionTimeEditField.Value = '0:00:00.000';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VortEx_GS_v3_6_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end